classdef ssc_poc_viewer_App_automatic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        EditField                      matlab.ui.control.EditField
        automatic_RF_selection         matlab.ui.control.Button
        save_QUS_and_figures_button    matlab.ui.control.Button
        process_QUS_button             matlab.ui.control.Button
        remove_RF_lines_button         matlab.ui.control.Button
        show_selected_RF_lines_button  matlab.ui.control.Button
        select_RF_lines_button         matlab.ui.control.Button
        show_RF_lines_button           matlab.ui.control.Button
        UseoldgreenlinelocationCheckBox  matlab.ui.control.CheckBox
        GreenlinelocationListBox       matlab.ui.control.ListBox
        GreenlinelocationListBoxLabel  matlab.ui.control.Label
        text_calib_folder              matlab.ui.control.Label
        select_calib                   matlab.ui.control.Button
        next_quspe                     matlab.ui.control.Button
        prev_quspe                     matlab.ui.control.Button
        good_pe_str                    matlab.ui.control.Label
        good_qus_str                   matlab.ui.control.Label
        next_folder                    matlab.ui.control.Button
        prev_folder                    matlab.ui.control.Button
        run_pe_button                  matlab.ui.control.Button
        run_qus_button                 matlab.ui.control.Button
        qus_checkbox                   matlab.ui.control.CheckBox
        pe_checkbox                    matlab.ui.control.CheckBox
        checkbox2                      matlab.ui.control.CheckBox
        text_filename                  matlab.ui.control.Label
        filter_checkbox                matlab.ui.control.CheckBox
        text8                          matlab.ui.control.Label
        text7                          matlab.ui.control.Label
        max_v_text                     matlab.ui.control.Label
        save_button                    matlab.ui.control.Button
        text5                          matlab.ui.control.Label
        text4                          matlab.ui.control.Label
        upper_db_text                  matlab.ui.control.EditField
        lower_db_text                  matlab.ui.control.EditField
        folder_text                    matlab.ui.control.TextArea
        folder_button                  matlab.ui.control.Button
        num_files_text                 matlab.ui.control.Label
        edit1                          matlab.ui.control.EditField
        prev_button                    matlab.ui.control.Button
        next_button                    matlab.ui.control.Button
        axes3                          matlab.ui.control.UIAxes
        axes2                          matlab.ui.control.UIAxes
        axes1                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function handles = change_folder(app, handles)
            % Change the active folder
            % For when pressing the "Prev", "Next", or "Select Folder" buttons

            handles.folder_text.String = handles.data_folder; % Update the text box indicating current folder location

            % Get a list of all .eye files in the selected folder
            % handles.eye_fid = dir([handles.data_folder,'\*.eye']);
            handles.eye_fid = return_ordered_eye(handles.data_folder);

            if isempty(handles.eye_fid)
                % No .eye files found. Look for a GE .bin file
                handles.eye_fid = dir([handles.data_folder,'\*B_RF*.bin']);

                if ~isempty(handles.eye_fid)
                    handles.use_ge_bin = true;
                    handles.use_eye = false;
                end
            else
                handles.use_eye = true;
                handles.use_ge_bin = false;
            end

            handles.num_eye = length(handles.eye_fid); % starts out with zero .eye files
            handles.current_eye = 1;

            % Added by elmira 20241104
            handles.RFstruct.RF_xbounds = [];
            handles.RFstruct.eye_mat = [];
            handles.RFstruct.eye_mat_partial = [];

            % Update the text boxes listing which .eye file in the folder is currently
            % displayed

            handles.edit1.String = num2str(handles.current_eye);
            handles.num_files_text.String = sprintf('of %d',handles.num_eye);

            handles = change_scan_plane(app, handles,0);

            handles = reset_qus_pe(handles); % Be sure any QUS and PE results in memory are erased
            handles.all_good_frames = {};
            handles.good_frame_idx = 0;

            % % Be sure to read in the data correctly depending on the file type
            % % Go ahead and plot the first image
            % if handles.use_eye == true
            %     handles.file_reader.readEye([handles.eye_fid(1).folder,'\',handles.eye_fid(1).name]);
            %
            %     % Pull out the RF data, convert to dB and normalize
            %     raw_rf = handles.file_reader.data;
            %     this_rf = handles.file_reader.data;
            %     this_rf = 20*log10(abs(hilbert(this_rf)));
            %     this_rf = this_rf - max(this_rf(:));
            %
            %     % Get scan settings to create axes vectors
            %     delta_x = handles.file_reader.settings.deltaX/1000; % spacing between scan lines in mm
            %     fs = handles.file_reader.settings.fs*1e6;
            %     c = handles.file_reader.settings.sos;
            %     num_pts = handles.file_reader.settings.rfpts;
            %     del_mm = handles.file_reader.settings.del; % axial offset
            % elseif handles.use_ge_bin == true
            %     rf_data = readIQRFDataFrame([handles.eye_fid(1).folder,'\',handles.eye_fid(1).name],'RF');
            %     % Assume a single 'firing'
            %     raw_rf = rf_data.data{1};
            %     this_rf = 20*log10(abs(hilbert(rf_data.data{1})));
            %     this_rf = this_rf - max(this_rf(:));
            %
            %     delta_x = 0.1; % Just assume 50um pitch. This data is not in the GE file header
            %     fs = rf_data.samplingRate;
            %     c = 1540; % Again, assume because it's not in the header
            %     num_pts = rf_data.nSamplesPerBeam;
            %     del_mm = 0; % assume no axial offset
            % end
            %
            % axial_vec = [0:num_pts-1].*c/(2*fs)*1000 + del_mm; % axial axes vector in mm
            % lateral_vec = [0:size(this_rf,2)-1]*delta_x; % lateral axes vector in mm
            %
            % % Update the rf_data structure with the current RF data
            % handles.rf_data.data = raw_rf;
            % handles.rf_data.axial_vec = axial_vec;
            % handles.rf_data.lateral_vec = lateral_vec;
            % handles.rf_data.fs = fs;
            % handles.rf_data.c = c;
            % handles.rf_data.delta_x = delta_x;
            %
            % set(handles.axes1,'NextPlot','replacechildren');
            % handles.im_handle = imagesc(handles.axes1,lateral_vec,axial_vec,this_rf,handles.db_range);
            % % Set the image to not be clickable. That way, any button clicks pass
            % % through to the axes handle.
            % handles.im_handle.PickableParts = 'none';
            % set(handles.axes1,'YDir','Reverse');
            %
            % colormap(handles.axes1,'gray');
            % % axis(handles.axes1,'image');
            % xlabel(handles.axes1,'Lateral (mm)');
            % ylabel(handles.axes1,'Axial (mm)');


            % Set the name of the currently file displayed
            this_filename = handles.eye_fid(1).name;
            [~,this_filename,~] = fileparts(this_filename);
            handles.text_filename.String = this_filename;

            handles.folder_selected = true;

            handles.plot_aline = false;

            % Compute the maximum resolvable voltage based on the ADC settings
            if handles.use_eye == true
                vscale  = handles.file_reader.settings.vscale;
                gain    = handles.file_reader.settings.gain;
                att     = handles.file_reader.settings.attenuation;
                bitsize = handles.file_reader.settings.bitsize;

                max_v = (2^(bitsize-1)) .* 10^((vscale-gain+att)/20)/(2^(bitsize)-1);

                handles.max_v_text.String = sprintf('Max voltage is % 6.4E',max_v);
            end


            % Load the lists of good PE and QUS data, if they exist
            handles.good_qus_cell = {};
            handles.good_pe_cell = {};
            search_fid = sprintf('%s\\poc_usable_data.mat',handles.data_folder);
            if exist(search_fid,'file')
                handles.usable_data_text.String = 'poc_usable_data.mat exists';
                load_poc_data = load(search_fid);
                if isfield(load_poc_data,'good_qus_cell')
                    handles.good_qus_cell = load_poc_data.good_qus_cell;
                else
                    handles.good_qus_cell = {};
                end

                if isfield(load_poc_data,'good_pe_cell')
                    handles.good_pe_cell = load_poc_data.good_pe_cell;
                else
                    handles.good_pe_cell = {};
                end

                handles.all_good_frames = [handles.good_qus_cell,handles.good_pe_cell];

            end

            % Update the number of good QUS/PE frames
            handles = update_qus_pe_count(handles);
        end

        function handles = change_scan_plane(app, handles, scan_inc)
            % --- Changes the scan plane currently displayed

            % hObject    handle to prev_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %
            % General function to change which scan plane is displayed. scan_inc
            % indicates the plane number relevant to the current plane displayed (i.e.;
            % handles.current_eye + scan_inc.
            %
            % Gets called by some of the other callback functions to consolidate code
            this_eye = handles.current_eye;
            this_eye = this_eye + scan_inc;

            if this_eye < 1 % be sure to not try and plot data that doesn't exist
                % Scan plane number must be at least 1
                this_eye = 1;
            elseif this_eye > handles.num_eye
                % The selected plane number is larger than the number of scan planes.
                this_eye = handles.num_eye;
            end

            % Reset the QUS and PE results, if they exist
            handles = reset_qus_pe(handles);

            % Read in the data for the next file and plot
            % Be sure to read in the data correctly depending on the file type
            if handles.use_eye == true
                handles.file_reader.readEye([handles.eye_fid(this_eye).folder,'\',handles.eye_fid(this_eye).name]);
                eye_fid = handles.eye_fid(this_eye).name;

                % Parse the file name to get acquisition info
                [p_id,p_freq,p_pos,p_side,acq_letter,acq_num] = ssc.parse_ssc_file_name(eye_fid);

                if strcmp(p_freq,'50MHz')
                    fc = 50;
                elseif strcmp(p_freq,'80MHz')
                    fc = 80;
                else
                    error('Unknown transducer frequency');
                end

                % Get scan settings to create axes vectors
                %     delta_x = handles.file_reader.settings.deltaX/1000; % spacing between scan lines in mm
                delta_x = 1/handles.prf;

                if delta_x == 0
                    delta_x = 1;
                end

                fs = handles.file_reader.settings.fs*1e6;
                c = 1485; % Same as what is hard coded in LabView %handles.file_reader.settings.sos;
                num_pts = handles.file_reader.settings.rfpts;
                del_mm = handles.file_reader.settings.del / handles.file_reader.settings.sos * c; %Changing offset due to change in SoS % handles.file_reader.settings.del; % axial offset

                % Define a bandpass filter based on whether the lower or higher
                % frequency transducer was found
                t1_find = strfind(handles.eye_fid(this_eye).name,'T1');
                t2_find = strfind(handles.eye_fid(this_eye).name,'T2');

                % Pull out the RF data, convert to dB and normalize
                raw_rf = handles.file_reader.data;
                this_rf = handles.file_reader.data;
                if handles.filter_checkbox.Value == 1
                    if ~isempty(t1_find)
                        % 35 MHz transducer
                        pass_band = [14 53].*1e6;
                        [b,a] = cheby2(10,80,pass_band/(fs/2));
                    elseif ~isempty(t2_find)
                        % 10 MHz transducer
                        pass_band = [1 20].*1e6;
                        [b,a] = cheby2(5,80,pass_band/(fs/2));
                    else
                        % assume the 80 MHz transducer
                        pass_band = [30 130].*1e6;
                        [b,a] = cheby2(10,80,pass_band/(fs/2));
                    end
                    %     [b,a] = cheby2(10,80,pass_band/(fs/2));

                    this_rf = filter(b,a,this_rf);
                end
                this_rf = 20*log10(abs(hilbert(this_rf)));
                this_rf = this_rf - max(this_rf(:));

            elseif handles.use_ge_bin == true
                rf_data = readIQRFDataFrame([handles.eye_fid(this_eye).folder,'\',handles.eye_fid(this_eye).name],'RF');
                % Assume a single 'firing'
                raw_rf = rf_data.data{1};
                this_rf = 20*log10(abs(hilbert(rf_data.data{1})));
                this_rf = this_rf - max(this_rf(:));

                delta_x = 0.1; % Just assume 50um pitch. This data is not in the GE file header
                fs = rf_data.samplingRate;
                c = 1540; % Again, assume because it's not in the header
                num_pts = rf_data.nSamplesPerBeam;
                del_mm = 0;
            end

            this_filename = handles.eye_fid(this_eye).name;

            % If this file is in the list of good PE or QUS frames, apply a tick to the
            % appropriate checkbox
            if ismember(this_filename,handles.good_pe_cell)
                handles.pe_checkbox.Value = 1;
            else
                handles.pe_checkbox.Value = 0;
            end

            if ismember(this_filename,handles.good_qus_cell)
                handles.qus_checkbox.Value = 1;
            else
                handles.qus_checkbox.Value = 0;
            end

            [~,this_filename,~] = fileparts(this_filename);
            handles.text_filename.String = this_filename;

            axial_vec = [0:num_pts-1].*c/(2*fs)*1000 + del_mm; % axial axes vector in mm
            %             lateral_vec = [0:size(this_rf,2)-1]*delta_x; % lateral axes vector in mm
            lateral_vec = [1:size(this_rf,2)]; % lateral axes is RF line # since no spatial information is present

            % Update the rf_data structure with the current RF data
            handles.rf_data.data = raw_rf;
            handles.rf_data.axial_vec = axial_vec;
            handles.rf_data.lateral_vec = lateral_vec;
            handles.rf_data.fs = fs;
            handles.rf_data.c = c;
            handles.rf_data.delta_x = delta_x;

            cla(handles.axes1);
            set(handles.axes1,'NextPlot','replacechildren');
            handles.im_handles = imagesc(handles.axes1,lateral_vec,axial_vec,this_rf,handles.db_range);
            set(handles.axes1,'YDir','Reverse');
            xl1 = lateral_vec(1);
            xl2 = lateral_vec(end);
            % Plot lines to indicate the processing region
            hold(handles.axes1,'on');

            if ~app.UseoldgreenlinelocationCheckBox.Value
                if fc == 50
                    yg = 12.5;
                elseif fc == 80
                    yg = 2.2;
                end
            else
                yg = str2double(app.GreenlinelocationListBox.Value);
            end
            if fc == 50
                yr1 = 12.7;
                yr2 = 13.5;
            elseif fc == 80
                yr1 = 2.4;
                yr2 = 2.6;
            end
            plot(handles.axes1,[xl1, xl2],[yr1, yr1],':r','LineWidth',2);
            plot(handles.axes1,[xl1, xl2],[yr2, yr2],':r','LineWidth',2);
            plot(handles.axes1,[xl1, xl2],[yg, yg],':g','LineWidth',2);

            % Added by Elmira. For automatic selection of RF lines
            handles.RFstruct.greenliney = yg;
            handles.RFstruct.redlines = [yr1, yr2];
            handles.RFstruct.fc = fc;

            % Remove any 'PickableParts' from the image so that button clicks pass
            % through to axes callback function
            handles.im_handles.PickableParts = 'none';

            % Go ahead and plot a horizontal line at 13.5 to indicate the distal edge
            % of the focal zone for the 30MHz transducer
            % hold(handles.axes1,'on');
            % plot(handles.axes1,[lateral_vec(1), lateral_vec(end)],[10.75 10.75],':r','LineWidth',1.5,'Tag','roi_line');
            % plot(handles.axes1,[lateral_vec(1), lateral_vec(end)],[13.5 13.5],':r','LineWidth',1.5,'Tag','roi_line');
            %
            % % Depth of field for the 10MHz transducer (2-27-20 estimate, at least)
            % plot(handles.axes1,[lateral_vec(1), lateral_vec(end)],[32.91 32.91],':r','LineWidth',1.5,'Tag','roi_line');
            % plot(handles.axes1,[lateral_vec(1), lateral_vec(end)],[44.54 44.54],':r','LineWidth',1.5,'Tag','roi_line');

            ax_lims = [lateral_vec(1) lateral_vec(end) axial_vec(1) axial_vec(end)];

            if fc == 80
                ax_lims(3) = 1.8;
                ax_lims(4) = 3.0;
            end

            colormap(handles.axes1,'gray');
            axis(handles.axes1,'image');
            axis(handles.axes1,'square');
            try
                xlim(handles.axes1,ax_lims(1:2));
            catch
            end
            ylim(handles.axes1,ax_lims(3:4));
            xlabel(handles.axes1,'RF Line #');
            ylabel(handles.axes1,'Axial (mm)');
            title_str = sprintf('Patient %s, %s, %s, %s',p_id, p_freq, p_pos, p_side);
            title(handles.axes1,title_str);


            % Update the text boxes listing which .eye file in the folder is currently
            % displayed
            handles.current_eye = this_eye;
            handles.edit1.String = num2str(handles.current_eye);

            % New data, so no A-line plot could exist yet.
            handles.plot_aline = false;
            % Nor could the ROI boundaries exist
            handles.plot_roi = false;

            if handles.plot_crop_bounds == false
                % Set the default cropping region to the whole image
                handles.crop_tl = [1 1];
                handles.crop_br = size(this_rf);
                handles.plot_crop_bounds = false;
            else
                % Some cropping boundaries already selected. Re-plot them
                plot_crop_boundary(app, hObject,eventdata,handles,[],true);
            end

            % Plot the QUS/PE ROI, if they exists
            plot_qus_pe_roi(handles);
        end

        function crop_button_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in crop_button.

            % hObject    handle to crop_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %
            % Function to crop the set of eye files - frames indicated by
            % first_frame_crop and last_frame_crop - using the selected boundaries
            % specified by crop_tl and crop_br (in indices)

            % First, make sure a target directory for saving cropped images has been
            % selected. Otherwise, exit
            if handles.crop_folder_selected == false
                warndlg('Must select crop destination folder')
                guidata(hObject,handles);
                return;
            end

            % Can only crop .eye file data
            if handles.use_eye == false
                warndlg('Can only crop .eye files');
                return;
            end

            % Create a folder in the target directory with the same name as the folder
            % containing the original set of data. If a folder of the same name already
            % exists, have the user decide to either overwrite or choose a new target
            % directory (the latter is done by pressing the "Set Crop Dir" button).
            slash_inds = strfind(handles.data_folder,'\');
            % The name of the current folder is everything in the data folder following
            % the last backslash
            current_folder = handles.data_folder(slash_inds(end)+1:end);

            save_folder = [handles.crop_folder,'\',current_folder];

            if exist(save_folder)
                % Folder already exists in target destination. Have user decide if it
                % should be overwritten
                user_resp = questdlg('Target directory already exists. Overwrite?','', ...
                    'Yes','No','No'); % Default to "No" in case user presses Enter/Return

                if isempty(user_resp) || strcmp(lower(user_resp),'no')
                    % User chose to not overwrite. Exit this function without updating
                    return;
                else
                    % User chose to overwrite. Remove the existing target folder (to
                    % delete any files) and recreate
                    rmdir(save_folder,'s');
                    mkdir(save_folder);
                end
            else
                % No folder of the same name exists in target directory. Create one.
                mkdir(save_folder);
            end

            % For the chosen set of frames, read in the contents of the .eye file, crop
            % the necessary data structures, and write the file to the target directory
            %
            % However, start the file numbering at 000, not the number associated with
            % the .eye file. These two will differ if first_frame_crop is not 1.
            new_sig = eye.EyeData;
            file_count = 0;

            % Create a waitbar as a visual indicator of how many frames have been
            % cropped
            total_crop_frames = range([handles.first_frame_crop, handles.last_frame_crop]);
            wait_fig = waitbar(file_count/total_crop_frames,sprintf('Frame %d of %d',file_count,total_crop_frames));

            for ff=handles.first_frame_crop:handles.last_frame_crop

                file_number = num2str(ff-1); % ff-1 because files names start at 0
                % Based on the file number, create the aXXX.eye portion of the file
                % name
                file_part = '000';
                file_part(end-length(file_number)+1:end) = file_number;
                file_part = ['a',file_part,'.eye'];
                % Find the whole file name
                this_file = dir([handles.data_folder,'\*',file_part]);
                full_fid = [this_file(1).folder,'\',this_file(1).name];

                if isempty(this_file)
                    error('File not found!');
                end

                % Go ahead and create the save name of the file the same way, using
                % file_count instead of file_number
                save_number = num2str(file_count);
                save_part = '000';
                save_part(end-length(save_number)+1:end) = save_number;
                save_part = ['a',save_part,'.eye'];

                if length(file_part) ~= length(save_part)
                    error('Problem with file names');
                end

                save_fid = this_file(1).name;
                a_find = strfind(save_fid,file_part);
                save_fid(a_find:end) = save_part; % Replace the file number
                save_fid = [save_folder,'\',save_fid];

                % If a file with the same name already exists, do NOT overwrite. Just
                % to avoid accidentally overwriting the raw data files.

                new_sig.readEye(full_fid); % read in the contents of the .eye file

                % Cut out the desired portion of the echo data
                % Define the axial and lateral start/end indices based on the top-left
                % and bottom-right crop corners
                ax_start = handles.crop_tl(1);
                ax_end = handles.crop_br(1);
                lat_start = handles.crop_tl(2);
                lat_end = handles.crop_br(2);
                new_data = new_sig.data;
                new_data = new_data(ax_start:ax_end,lat_start:lat_end);

                % And need to adjust the rfpts field
                new_sig.settings.setRfpts(size(new_data,1));
                new_sig.settings.setLines(size(new_data,2));
                new_timevec = new_sig.createTimeVec.*1e-6; % Need to keep in units of seconds
                new_sig.setSig(new_data,new_timevec);

                % Save the cropped data
                new_sig.writeEye(save_fid);

                file_count = file_count + 1;

                waitbar(file_count/total_crop_frames,wait_fig,sprintf('Frame %d of %d',file_count,total_crop_frames));
            end

            close(wait_fig);

            % In the directory where the cropped images are saved, create a text file
            % that specifies which frames are included in the cropped data set, the
            % corners (indices) of the cropped area, and the (x,y) points as specified
            % by the corner indices.
            %
            % I don't know if this data will ever be needed, but it's probably better
            % to save it.
            tl = handles.crop_tl; % copy a few variables from the handles structure just to reduce
            br = handles.crop_br; % the amount of text in the following lines
            axial_vec = handles.rf_data.axial_vec;
            lateral_vec = handles.rf_data.lateral_vec;

            out_fid = [save_folder,'\crop_info.txt'];
            out_file = fopen(out_fid,'w+'); % Open file for writing, erasing any existing contents
            fprintf(out_file,'Cropped frames %d to %d.\n',handles.first_frame_crop,handles.last_frame_crop);
            fprintf(out_file,'\n');
            fprintf(out_file,'Top-left corner indices (row,column): (%d, %d)\n',handles.crop_tl);
            fprintf(out_file,'Bottom-right cornder indices(row,column): (%d, %d)\n',handles.crop_br);
            fprintf(out_file,'\n');
            fprintf(out_file,'Top-left corner coordinates (x,y): (% 5.4f, %5.4f)\n',lateral_vec(tl(2)),axial_vec(tl(1)));
            fprintf(out_file,'Bottom-right corner coordinates (x,y): (% 5.4f, %5.4f)\n',lateral_vec(br(2)),axial_vec(br(1)));

            fclose(out_file);

            guidata(hObject,handles);
        end

        function crop_folder_text_Callback(app, hObject, eventdata, handles)
            % hObject    handle to crop_folder_text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of crop_folder_text as text
            %        str2double(get(hObject,'String')) returns contents of crop_folder_text as a double
            %
            % Do not allow the user to manually change the string in this text box.
            handles.crop_folder_text.String = handles.crop_folder;

            guidata(hObject,handles);
        end

        function crop_folder_text_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.

            % hObject    handle to crop_folder_text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function first_frame_edit_Callback(app, hObject, eventdata, handles)
            % hObject    handle to first_frame_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of first_frame_edit as text
            %        str2double(get(hObject,'String')) returns contents of first_frame_edit as a double

            % Allows the user to set the first frame in the series when cropping
            user_eye = str2num(handles.first_frame_edit.String);

            % First, make sure a number was entered
            if isempty(user_eye)
                % Something other than a number was entered. Don't change the frame.
                handles.first_frame_edit.String = num2str(handles.first_frame_crop);
                guidata(hObject,handles);
                return;
            end

            % A number was entered. Make sure it is between 1 and num_eye
            % Also, cannot go beyond the last frame specified for cropping
            if user_eye < 1 || user_eye > handles.num_eye || user_eye > handles.last_frame_crop
                % An out of bounds number was entered. Don't update
                handles.first_frame_edit.String = num2str(handles.first_frame_crop);
                guidata(hObject,handles);
                return;
            end

            handles.first_frame_crop = user_eye;
            handles.first_frame_edit.String = num2str(handles.first_frame_crop);

            guidata(hObject,handles);
        end

        function first_frame_edit_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.

            % hObject    handle to first_frame_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function last_frame_edit_Callback(app, hObject, eventdata, handles)
            % hObject    handle to last_frame_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of last_frame_edit as text
            %        str2double(get(hObject,'String')) returns contents of last_frame_edit as a double

            % Allows the user to set the last frame in the series when cropping
            user_eye = str2num(handles.last_frame_edit.String);

            % First, make sure a number was entered
            if isempty(user_eye)
                % Something other than a number was entered. Don't change the frame.
                handles.last_frame_edit.String = num2str(handles.last_frame_crop);
                guidata(hObject,handles);
                return;
            end

            % A number was entered. Make sure it is between 1 and num_eye
            % Also, cannot go beyond the last frame specified for cropping
            if user_eye < 1 || user_eye > handles.num_eye || user_eye < handles.first_frame_crop
                % An out of bounds number was entered. Don't update
                handles.last_frame_edit.String = num2str(handles.last_frame_crop);
                guidata(hObject,handles);
                return;
            end

            handles.last_frame_crop = user_eye;
            handles.last_frame_edit.String = num2str(handles.last_frame_crop);

            guidata(hObject,handles);
        end

        function last_frame_edit_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.

            % hObject    handle to last_frame_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function plot_crop_boundary(app, hObject, eventdata, handles, current_key, plot_existing)
            % Function to plot the boundaries of the region selected for cropping
            % current_key specifies which corner of the ROI was selected.
            % SHIFT: top-left corner
            % CTRL: top-right corner
            %
            % if plot_existing == true, then plot the existing crop boundaries

            if ~isempty(plot_existing) && plot_existing == true
                crop_tl = handles.crop_tl;
                crop_br = handles.crop_br;

                line_tag = 'crop_line_tl';
                xv = [handles.rf_data.lateral_vec(crop_tl(2)) handles.rf_data.lateral_vec(end)];
                yv = [handles.rf_data.axial_vec(crop_tl(1)), handles.rf_data.axial_vec(end)];
                plot(handles.axes1,xv,[yv(1) yv(1)],':g','LineWidth',2,'Tag',line_tag);
                plot(handles.axes1,[xv(1), xv(1)],yv,':g','LineWidth',2,'Tag',line_tag);

                line_tag = 'crop_line_br';
                xv = [handles.rf_data.lateral_vec(1) handles.rf_data.lateral_vec(crop_br(2))];
                yv = [handles.rf_data.axial_vec(1), handles.rf_data.axial_vec(crop_br(1))];
                plot(handles.axes1,xv,[yv(end) yv(end)],':g','LineWidth',2,'Tag',line_tag);
                plot(handles.axes1,[xv(end), xv(end)],yv,':g','LineWidth',2,'Tag',line_tag);

                guidata(hObject,handles);

                return
            end

            % Position on image where mouse-click occurred
            this_point = handles.axes1.CurrentPoint(1,:);

            % Find the index corresponding to the selected x-position
            x_ind = find(this_point(1) < handles.rf_data.lateral_vec);
            x_ind = x_ind(1);
            y_ind = find(this_point(2) < handles.rf_data.axial_vec);
            y_ind = y_ind(1);

            if strcmp(current_key,'shift')
                tl_corner = true;
                br_corner = false;
                line_tag = 'crop_line_tl';
                handles.crop_tl = [y_ind, x_ind];
            elseif strcmp(current_key,'control')
                tl_corner = false;
                br_corner = true;
                line_tag = 'crop_line_br';
                handles.crop_br = [y_ind, x_ind];
            end

            % But, before adding a line, delete any existing line objects corresponding
            % to this crop corner
            line_obj = findobj(handles.axes1.Children,'Type','Line');
            if ~isempty(line_obj)
                % Only delete a line if it does not mark the ROI boundaries. I.e.,
                % ignore any line objects with Tag "roi_line"
                for ll=1:length(line_obj)
                    if strcmp(line_obj(ll).Tag,line_tag);
                        delete(line_obj(ll));
                    end
                end
            end

            % When plotting lines for the cropping boundaries, have the lines extend
            % from the corner, along the edge of the boundary, and continue to the
            % image edge.
            hold(handles.axes1,'on');
            if tl_corner
                xv = [handles.rf_data.lateral_vec(x_ind) handles.rf_data.lateral_vec(end)];
                yv = [handles.rf_data.axial_vec(y_ind), handles.rf_data.axial_vec(end)];
                plot(handles.axes1,xv,[yv(1) yv(1)],':g','LineWidth',2,'Tag',line_tag);
                plot(handles.axes1,[xv(1), xv(1)],yv,':g','LineWidth',2,'Tag',line_tag);
            elseif br_corner
                xv = [handles.rf_data.lateral_vec(1) handles.rf_data.lateral_vec(x_ind)];
                yv = [handles.rf_data.axial_vec(1), handles.rf_data.axial_vec(y_ind)];
                plot(handles.axes1,xv,[yv(end) yv(end)],':g','LineWidth',2,'Tag',line_tag);
                plot(handles.axes1,[xv(end), xv(end)],yv,':g','LineWidth',2,'Tag',line_tag);
            end

            handles.plot_crop_bounds = true;

            guidata(hObject,handles);

            return
        end

        function plot_roi_boundary(app, hObject, eventdata, handles)
            % Function to plot the ROI boundary and compute the average spectrum of the
            % RF data within the ROI

            axial_vec = handles.rf_data.axial_vec;
            lateral_vec = handles.rf_data.lateral_vec;
            this_rf = handles.rf_data.data;
            fs = handles.rf_data.fs;

            % Find the array indices corresponding to the top-left ROI corner
            this_point = handles.roi_tl;
            tl_ind(1) = find(this_point(1) < lateral_vec,1,'first');
            tl_ind(2) = find(this_point(2) < axial_vec,1,'first');

            % Find the array indices corresponding to the bottom-right ROI corner
            this_point = handles.roi_br;
            br_ind(1) = find(this_point(1) < lateral_vec,1,'first');
            br_ind(2) = find(this_point(2) < axial_vec,1,'first');

            sub_ax_vec = axial_vec(tl_ind(2):br_ind(2));
            sub_lat_vec = lateral_vec(tl_ind(1):br_ind(1));

            % Delete any existing ROI rectangle
            rect_obj = findobj(handles.axes1.Children,'Tag','roi_rect');
            if ~isempty(rect_obj)
                % Only delete a line if it does not mark the ROI boundaries. I.e.,
                % ignore any line objects with Tag "roi_line"
                for ll=1:length(rect_obj)
                    if strcmp(rect_obj(ll).Tag,'roi_rect');
                        delete(rect_obj(ll));
                    end
                end
            end

            point_obj = findobj(handles.axes1.Children,'Tag','roi_point');
            if ~isempty(point_obj)
                delete(point_obj);
            end


            % Draw the rectangle
            rectangle(handles.axes1,'Position',[handles.roi_tl(1), handles.roi_tl(2),range(sub_lat_vec), range(sub_ax_vec)], ...
                'Tag','roi_rect','EdgeColor','m','LineWidth',1.5);

            sub_rf = this_rf(tl_ind(2):br_ind(2),tl_ind(1):br_ind(1));
            sub_rf = sub_rf - mean(sub_rf(:));
            npad = 2^nextpow2(size(sub_rf,1));
            this_spec = 20*log10(abs(fft(sub_rf,npad)));
            this_spec = mean(this_spec,2);
            this_spec = fftshift(this_spec);

            df = fs/npad;
            f_vec = -fs/2:df:(fs/2-df);

            keep_inds = npad/2+1:npad;
            this_spec = this_spec(keep_inds);
            f_vec = f_vec(keep_inds);

            % this_spec = this_spec - max(this_spec(:));

            this_bmode = 20*log10(abs(hilbert(sub_rf)));
            this_bmode = this_bmode - max(this_bmode(:));

            figure(300); clf;
            subplot(2,1,1);
            imagesc(sub_lat_vec,sub_ax_vec,this_bmode,[-45 0]);
            colormap gray;
            xlabel('Lateral (mm)');
            ylabel('Axial (mm)');
            title('B-mode of ROI (Magenta Rectangle)');

            subplot(2,1,2);
            plot(f_vec/1e6,this_spec,'k','LineWidth',2);
            xlabel('Frequency (MHz)');
            ylabel('Power (dB)');
            title('Average Spectrum in ROI');

            save('.\average_spec.mat','f_vec','this_spec');

            handles.plot_roi = true;

            guidata(hObject,handles);
        end

        function preview_crop_button_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in preview_crop_button.

            % hObject    handle to preview_crop_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %
            % Function to plot the cropped version of the image displayed in the main
            % GUI axes.

            crop_tl = handles.crop_tl;
            crop_br = handles.crop_br;

            crop_rf = handles.rf_data.data;
            crop_rf = crop_rf(crop_tl(1):crop_br(1),crop_tl(2):crop_br(2));
            crop_rf = 20*log10(abs(hilbert(crop_rf)));
            crop_rf = crop_rf - max(crop_rf(:));

            this_axial_vec = handles.rf_data.axial_vec;
            this_lateral_vec = handles.rf_data.lateral_vec;
            this_axial_vec = this_axial_vec(crop_tl(1):crop_br(1));
            this_lateral_vec = this_lateral_vec(crop_tl(2):crop_br(2));

            figure(1000); clf;
            imagesc(this_lateral_vec,this_axial_vec,crop_rf,handles.db_range);
            axis image;
            colormap gray;

            figure(handles.figure1);
            guidata(hObject,handles);
        end

        function set_crop_dir_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in set_crop_dir.

            % hObject    handle to set_crop_dir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Have user choose directory for saving cropped data
            crop_dir = uigetdir('Choose Folder');

            if isempty(crop_dir)
                % No folder selected. Do not update anything.
                return;
            end

            % Set the corresponding text box with the chosen directory (used for
            % convenience when copy-pasting)
            handles.crop_folder_selected = true;
            handles.crop_folder = crop_dir;
            handles.crop_folder_text.String = handles.crop_folder;

            guidata(hObject,handles);
        end
        %__________________________________________________________________________
        function handles = show_RF_lines(app, handles, mode)
            if strcmp(mode,'whole')
                im = handles.RFstruct.eye_mat;
            elseif strcmp(mode,'partial')
                im = handles.RFstruct.eye_mat_partial;
            end
            
            % Parse the file name to get acquisition info
            eye_fid = handles.eye_fid(1).name;
            [~,p_freq,~,~,~,~] = ssc.parse_ssc_file_name(eye_fid);

            if strcmp(p_freq,'50MHz')
                fc = 50;
            elseif strcmp(p_freq,'80MHz')
                fc = 80;
            else
                error('Unknown transducer frequency');
            end

            this_rf = 20*log10(abs(hilbert(im)));
            this_rf = this_rf - max(this_rf(:));

            axial_vec = [0:handles.RFstruct.num_pts-1].*handles.RFstruct.c/...
                (2*handles.RFstruct.fs)*1000 + ...
                handles.RFstruct.del_mm; % axial axes vector in mm
            lateral_vec = [1:size(im,2)]; % lateral axes is RF line # since no spatial information is present

            handles.RFstruct.axial_vec = axial_vec;
            handles.RFstruct.lateral_vec = lateral_vec;
            handles.RFstruct.fc = fc;

            cla(handles.axes2);
            % set(handles.axes2,'NextPlot','replacechildren');
            handles.im_handles = imagesc(handles.axes2,lateral_vec,axial_vec,this_rf,handles.db_range);
            set(handles.axes2,'YDir','Reverse');
            xl1 = lateral_vec(1);
            xl2 = lateral_vec(end);
            % Plot lines to indicate the processing region
            hold(handles.axes2,'on');

            if ~app.UseoldgreenlinelocationCheckBox.Value
                if fc == 50
                    yg = 12.5;
                elseif fc == 80
                    yg = 2.2;
                end
            else
                yg = str2double(app.GreenlinelocationListBox.Value);
            end

            handles.RFstruct.greenliney = yg;
            if fc == 50
                yr1 = 12.7;
                yr2 = 13.5;
            elseif fc == 80
                yr1 = 2.4;
                yr2 = 2.6;
            end
            plot(handles.axes2,[xl1, xl2],[yr1, yr1],':r','LineWidth',2);
            plot(handles.axes2,[xl1, xl2],[yr2, yr2],':r','LineWidth',2);
            plot(handles.axes2,[xl1, xl2],[yg, yg],':g','LineWidth',2);

            % % Remove any 'PickableParts' from the image so that button clicks pass
            % % through to axes callback function
            % handles.im_handles.PickableParts = 'none';

            ax_lims = [lateral_vec(1) lateral_vec(end) axial_vec(1) axial_vec(end)];

            if fc == 80
                ax_lims(3) = 1.8;
                ax_lims(4) = 3.0;
            end

            colormap(handles.axes2,'gray');
            axis(handles.axes2,'normal');
            try
                xlim(handles.axes2,ax_lims(1:2));
            catch
            end
            ylim(handles.axes2,ax_lims(3:4));
            xlabel(handles.axes2,'RF Line #');
            ylabel(handles.axes2,'Axial (mm)');
        end

        function handles = show_selected_RF_lines(app, handles, im)
            this_rf = 20*log10(abs(hilbert(im)));
            this_rf = this_rf - max(this_rf(:));

            axial_vec = [0:handles.RFstruct.num_pts-1].*handles.RFstruct.c/...
                (2*handles.RFstruct.fs)*1000 + ...
                handles.RFstruct.del_mm; % axial axes vector in mm
            lateral_vec = [1:size(this_rf,2)]; % lateral axes is RF line # since no spatial information is present

            cla(app.axes3);
            handles.im_handles = imagesc(app.axes3,lateral_vec,axial_vec,this_rf,handles.db_range);
            set(app.axes3,'YDir','Reverse');
            xl1 = lateral_vec(1);
            xl2 = lateral_vec(end);
            % Plot lines to indicate the processing region
            hold(app.axes3,'on');

            if ~app.UseoldgreenlinelocationCheckBox.Value
                if handles.RFstruct.fc == 50
                    yg = 12.5;
                elseif handles.RFstruct.fc == 80
                    yg = 2.2;
                end
            else
                yg = str2double(app.GreenlinelocationListBox.Value);
            end
            if handles.RFstruct.fc == 50
                yr1 = 12.7;
                yr2 = 13.5;
            elseif handles.RFstruct.fc == 80
                yr1 = 2.4;
                yr2 = 2.6;
            end

            tolgreen1 = axial_vec(min(handles.RFstruct.tolerated_green));
            tolgreen2 = axial_vec(max(handles.RFstruct.tolerated_green));

            plot(app.axes3,[xl1, xl2],[yr1, yr1],':r','LineWidth',2);
            plot(app.axes3,[xl1, xl2],[yr2, yr2],':r','LineWidth',2);
            plot(app.axes3,[xl1, xl2],[yg, yg],':g','LineWidth',2);
            plot(app.axes3,[xl1, xl2],[tolgreen1, tolgreen1],'--g','LineWidth',2);
            plot(app.axes3,[xl1, xl2],[tolgreen2, tolgreen2],'--g','LineWidth',2);

            ax_lims = [lateral_vec(1) lateral_vec(end) axial_vec(1) axial_vec(end)];

            if handles.RFstruct.fc == 80
                ax_lims(3) = 1.8;
                ax_lims(4) = 3.0;
            end

            colormap(app.axes3,'gray');
            axis(app.axes3,'normal');
            try
                xlim(app.axes3,ax_lims(1:2));
            catch
            end
            ylim(app.axes3,ax_lims(3:4));
            xlabel(app.axes3,'RF Line #');
            ylabel(app.axes3,'Axial (mm)');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ssc_poc_viewer_OpeningFcn(app, varargin)
            % --- Executes just before ssc_poc_viewer is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ssc_poc_viewer (see VARARGIN)
            
            % cd('F:\OneDrive - med.cornell.edu\Documents\Scleroderma_Project\Patient_Data');
            
            % Choose default command line output for ssc_poc_viewer
            handles.output = hObject;
            
            % Initialize the list of .eye files to be empty
            handles.eye_fid = [];
            handles.num_eye = 0; % starts out with zero .eye files
            handles.current_eye = 0;
            handles.folder_selected = false; % flag to tell when a data folder has been selected
            handles.data_folder = [];
            handles.file_reader = eye.EyeData;
            handles.db_range = [-40 0];
            handles.use_eye = false;
            handles.use_ge_bin = false;
            handles.im_handle = []; % handle to the plotted image. May be used later to set callback
            handles.rf_data = []; % structure that contains information about the current RF data
            handles.plot_aline = false; % specifies if an A-line has been plotted
            handles.aline_position = []; % specifies the lateral position of the A-line that was plotted.
            handles.crop_tl = []; % specifies the top-left corner of the region to crop (in indices)
            handles.crop_br = []; % specifies the bottom-right corner of the region to crop (in indices)
            handles.plot_crop_bounds = false; % specifies if crop boundaries have been plotted.
            handles.first_frame_crop = 1;
            handles.last_frame_crop = 1;
            handles.crop_folder_selected = false; % Default that no target directory to save cropped images has been selected
            handles.crop_folder = [];
            handles.calib_folder = [];
            
            handles.right_click_count = 0; % tracks right-clicks for selecting ROI
            handles.roi_tl = []; % top-left corner of ROI
            handles.roi_br = []; % bottom-right corner of ROI
            handles.plot_roi = false;  % specifies if the ROI box has been plotted
            
            % No default folder from which to read .eye files
            handles.folder_text.String = 'None Selected';
            handles.num_files_text.String = sprintf('of %d',handles.num_eye);
            handles.edit1.String = '0';
            
            % Member that will hold the lists of good PE and QUS data
            handles.good_qus_cell = {};
            handles.good_pe_cell = {};
            handles.all_good_frames = {}; % to store all good PE/QUS frames
            handles.good_frame_idx = 0; % Tracks which good QUS/PE is to be shown
                    % Used by the Prev/Next QUS/PE push buttons
            
            handles.usable_data_text.String = 'No poc_usable_data.mat';
            handles.poc_save_str = 'poc_usable_data.mat';
            
            % Set the default dB range
            handles.lower_db_text.String = num2str(handles.db_range(1));
            handles.upper_db_text.String = num2str(handles.db_range(2));
            
            % New data, so no A-line plot could exist yet.
            handles.plot_aline = false;
            
            % QUS and PE data objects populated after QUS or PE processing
            handles.qus_params = [];
            handles.pe_params = [];
            handles.qus_pe_saved = false;
            
            handles.prf = 6969; % Hardcode the PRF [Hz]
            handles.c = 1540; % speed of sound in skin [m/s]

            % Added by Elmira 20241104
            handles.oldgreenline = false;
            handles.RFstruct = struct;
            handles.RFstruct.qus_from_rflines = false;
            handles.RFstruct.temp_eye_mat_partial = [];

            % Set a default startup folder and move there
            default_startup = 'E:\OneDrive new location\OneDrive - Weill Cornell Medicine\BURL\Scleroderma project\Patient data';
            if ~isfolder(default_startup)
                default_startup = 'D:\SSc_Data'; % For running in the HSS PC
            end
            cd(default_startup);
            
            % Assume the GUI was started in the Patient_Data folder that contains all
            % the folders with datasets. Generate a list of all of actual data
            % folders to allow for easily cycling through when processing. I.e., click
            % "Next Folder" instead of "Select Folder" and going through the Explorer
            % to find the next folder
            try
                handles.all_poc_folders = ssc.get_ssc_folders();
            catch
                handles.all_poc_folders = {cd};
            end
            
            if isempty(handles.all_poc_folders)
                errordlg('POC_Viewer must be started in patient data folder')
            end
            
            % Go ahead and move to the first folder in the list
            if ~isempty(handles.all_poc_folders)
                handles.current_folder = 1;
                handles.num_poc_folders = length(handles.all_poc_folders);
                handles.data_folder = handles.all_poc_folders{handles.current_folder};
                handles = change_folder(app, handles);
            end
            
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button down function: axes1
        function axes1_ButtonDownFcn(app, event)
            % --- Executes on mouse press over axes background.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to axes1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if handles.folder_selected == false;
                % No data folder has been selected. Thus, there's no data to plot
                return;
            end
            
            if eventdata.Button == 3
                % right-click. This is used to select a ROI. Increment the count of
                % right-clicks. If this is the second in a series, draw box around the
                % ROI and compute the average spectrum.
                handles.right_click_count = handles.right_click_count + 1;
            
                if handles.right_click_count == 1
                    handles.roi_tl = handles.axes1.CurrentPoint(1,:);
                    % Add a point to the plot to indicate the first selected corner
                    scatter(handles.axes1,handles.roi_tl(1),handles.roi_tl(2),60,'m','filled','Tag','roi_point');
                elseif handles.right_click_count == 2
                    handles.roi_br = handles.axes1.CurrentPoint(1,:);
                end
            
                if handles.right_click_count == 2
                    % Make sure the top-left and bottom-right corners were selected in
                    % order. If not, reset the click count without drawing box around
                    % ROI or computing average spectrum
                    handles.right_click_count = 0;
            
                    if any(handles.roi_br < handles.roi_tl)
                        % not selected in order
                        % Go ahead and delete any ROI boxes
                        rect_obj = findobj(handles.axes1.Children,'Tag','roi_rect');
                        if ~isempty(rect_obj)
                            % Only delete a line if it does not mark the ROI boundaries. I.e.,
                            % ignore any line objects with Tag "roi_line"
                            for ll=1:length(rect_obj)
                                if strcmp(rect_obj(ll).Tag,'roi_rect');
                                    delete(rect_obj(ll));
                                end
                            end
                        end
            
                        point_obj = findobj(handles.axes1.Children,'Tag','roi_point');
                        if ~isempty(point_obj)
                            delete(point_obj);
                        end
            
                        handles.plot_roi = false;
                    else
                        % Draw the ROI box and compute the average spectrum
                        plot_roi_boundary(app, hObject,eventdata,handles);
                        handles.plot_roi = true;
                    end
                end
                guidata(hObject,handles);
                return;
            end
            
            
            % Plot the RF line at the selected point
            this_point = handles.axes1.CurrentPoint(1,:);
            
            current_key = get(handles.figure1,'currentmodifier');
            % set(handles.figure1,'currentkey','');
            
            % If the CTRL or SHIFT keys were held while clicking on the image, set a
            % corner for cropping. If SHIFT, it's the top-left corner. If CTRL, it's
            % the bottom-right corner.
            if ~isempty(current_key)
                if strcmp(current_key,'control') || strcmp(current_key,'shift')
                    plot_crop_boundary(app, hObject,eventdata,handles,current_key,false);
                    return;
                end
            end
            
            % Find the index corresponding to the selected x-position
            x_ind = find(this_point(1) < handles.rf_data.lateral_vec);
            x_ind = x_ind(1);
            
            this_rf_line = handles.rf_data.data(:,x_ind);
            rf_adjust_val = mean(this_rf_line); % put in its own variable because it may be used to adjust limits later
            this_rf_line = this_rf_line - rf_adjust_val;
            
            % Plot the A-line and its envelop
            this_env = abs(hilbert(this_rf_line));
            figure(200); clf;
            subplot(2,1,1);
            plot(handles.rf_data.axial_vec,this_rf_line,'k','LineWidth',1.5);
            % hold on
            % plot(handles.rf_data.axial_vec,this_env,'r','LineWidth',1.5);
            xlim([handles.rf_data.axial_vec(1), handles.rf_data.axial_vec(end)]);
            xlabel('Axial (mm)');
            ylabel('RF (volts)');
            title('RF Signal (red line)');
            
            axial_vec = handles.rf_data.axial_vec;
            rf_line = this_rf_line;
            save('.\example_aline','axial_vec','rf_line');
            
            % If the data comes from a .eye file, adjust the y-limits to be the max
            % allowable voltage based on the ADC settings
            if handles.use_eye == true
                max_v = handles.max_v_text.String;
                max_v = strsplit(max_v);
                max_v = str2num(max_v{end});
                ylim([-max_v max_v]);
            end
            
            if handles.checkbox2.Value > 0
                y_max = max(abs(this_rf_line));
                y_max = max(abs(handles.rf_data.data(:)));
                ylim([-y_max, y_max]);
            end
            
            % Also plot the spectrum of the RF line
            % Compute the power spectrum for each A-line
            N = length(this_rf_line);
            this_spec = abs(fftshift(fft(this_rf_line),1));
            % Keep only the positive components
            this_spec = this_spec(ceil(N/2):end,:);
            
            % Create the frequency axis vector
            fs = handles.rf_data.fs;
            df = fs/N;
            f_vec = -fs/2:df:(fs/2 - df);
            f_vec = f_vec(ceil(N/2):end); % Again, keep only positive half
            
            this_spec = 20*log10(this_spec);
            this_spec = this_spec - max(this_spec(:));
            
            subplot(2,1,2);
            plot(f_vec/1e6,this_spec,'r','LineWidth',2);
            xlabel('Frequency (MHz)');
            ylabel('Normalized Power (dB)');
            title('Power Spectrum');
            ylim([-70 5]);
            xlim([f_vec(1) f_vec(end)]/1e6);
            
            % Go ahead and plot a line on the B-mode image to indicate which RF line is
            % being displayed
            %
            % But, before adding a line, delete any existing line objects, except:
            % Depth of field boundary lines (roi_line)
            % Crop boundary lines (crop_line_tl or crop_line_br)
            line_obj = findobj(handles.axes1.Children,'Type','Line');
            if ~isempty(line_obj)
                % Only delete a line if it does not mark the ROI boundaries. I.e.,
                % ignore any line objects with Tag "roi_line"
                for ll=1:length(line_obj)
                    roi_find = strfind(line_obj(ll).Tag,'roi_line');
                    crop_find = strfind(line_obj(ll).Tag,'crop_line');
                    if isempty(roi_find) && isempty(crop_find)
                        delete(line_obj(ll));
                    end
                end
            end
            
            hold(handles.axes1,'on');
            plot(handles.axes1,[this_point(1), this_point(1)],[handles.rf_data.axial_vec(1), handles.rf_data.axial_vec(end)],'r','LineWidth',1.25);
            
            handles.plot_aline = true;
            handles.aline_position = this_point(1);
            
            % Bring the main GUI window back into focus
            figure(handles.figure1);
            
            guidata(hObject,handles);
        end

        % Value changed function: checkbox2
        function checkbox2_Callback(app, event)
            % --- Executes on button press in checkbox2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox2
            
            % Nothing needs to be done here. The value is checked when plotting the
            % selected A-line for rescaling.
            guidata(hObject,handles)
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            %
            % Allows the user to type is a desired frame
            user_eye = str2num(handles.edit1.String);
            
            % First, make sure a number was entered
            if isempty(user_eye)
                % Something other than a number was entered. Don't change the frame.
                handles.edit1.String = num2str(handles.current_eye);
                guidata(hObject,handles);
                return;
            end
            
            % A number was entered. Make sure it is between 1 and num_eye
            if user_eye < 1 || user_eye > handles.num_eye
                % An out of bounds number was entered. Don't update
                handles.edit1.String = num2str(handles.current_eye);
                guidata(hObject,handles);
                return;
            end
            
            % Difference between current scan plane and the one desired.
            scan_diff = user_eye - handles.current_eye;
            
            handles = change_scan_plane(app, handles,scan_diff);
            
            guidata(hObject,handles);
            
            return
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            % --- Executes on key press with focus on figure1 and none of its controls.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            
            if strcmp(eventdata.Key,'uparrow')
                change_inc = 1;
            elseif strcmp(eventdata.Key,'downarrow')
                change_inc = -1;
            elseif strcmp(eventdata.Key,'rightarrow')
                % Skip +10 scan planes
                change_inc = 10;
            elseif strcmp(eventdata.Key,'leftarrow')
                % Skip -10 scan planes
                change_inc = -10;
            else
                return;
            end
            
            handles = change_scan_plane(app, handles,change_inc);
            
            guidata(hObject,handles);
        end

        % Value changed function: filter_checkbox
        function filter_checkbox_Callback(app, event)
            % --- Executes on button press in filter_checkbox.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to filter_checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of filter_checkbox
            
            % Replot the current data with/without filter applied
            handles = change_scan_plane(app, handles, 0);
            
            guidata(hObject,handles)
        end

        % Button pushed function: folder_button
        function folder_button_Callback(app, event)
            % --- Executes on button press in folder_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to folder_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %
            % Allows the user to select the folder in which the target .eye files are
            % stored
            
            if handles.folder_selected == false
                % If no folder previously selected, start in current working directory
                handles.data_folder = uigetdir('Select Data Folder');
            else
                % Otherwise, start in the last selected folder
                handles.data_folder = uigetdir(handles.data_folder,'Select Data Folder');
            end
            
            if isempty(handles.data_folder) | handles.data_folder == 0
                % No folder selected
                return
            end
            
            handles = change_folder(app, handles);
            
            % If the selected folder is in the list of patient data folders, update the
            % current folder number
            find_idx = find(strcmp(handles.data_folder,handles.all_poc_folders));
            if ~isempty(find_idx)
                handles.current_folder = find_idx;
            end
            
            % Empty the eye mat files
            handles.RFstruct.eye_mat_partial = [];
            handles.RFstruct.temp_eye_mat_partial = [];
            handles.RFstruct.eye_mat = [];
            app.EditField.Value = '';

            guidata(hObject,handles);
        end

        % Value changed function: folder_text
        function folder_text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to folder_text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of folder_text as text
            %        str2double(get(hObject,'String')) returns contents of folder_text as a double
            %
            % Edit text box that displays the current active folder. The only reason
            % it's an Edit box is so that the user can easily highlight and copy the
            % folder path. Thus, only change the listed path when a new folder is
            % selected using the "Select Folder" button.
            handles.folder_text.String = handles.data_folder;
            
            guidata(hObject,handles)
        end

        % Value changed function: lower_db_text
        function lower_db_text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lower_db_text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of lower_db_text as text
            %        str2double(get(hObject,'String')) returns contents of lower_db_text as a double
            %
            % Set the lower dB bound
            %
            % First, make sure a number was entered and, if it was a number, that it is
            % not larger than the dB range upper bound
            this_num = str2num(handles.lower_db_text.String);
            if isempty(this_num) || this_num > handles.db_range(2)
                % Make no change
                handles.lower_db_text.String = num2str(handles.db_range(1));
                guidata(hObject,handles);
                return
            end
            
            % Valid number in range, change the dB scale of the axes
            handles.db_range(1) = this_num;
            caxis(handles.axes1,handles.db_range);
            
            guidata(hObject,handles);
        end

        % Button pushed function: next_button
        function next_button_Callback(app, event)
            % --- Executes on button press in next_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to next_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %
            % Plot the B-mode of the next .eye file
            handles = change_scan_plane(app, handles,1);
            guidata(hObject,handles);
        end

        % Button pushed function: next_folder
        function next_folder_Callback(app, event)
            % --- Executes on button press in next_folder.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to next_folder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Move to the next folder in the list of all POC folders
            if handles.current_folder < handles.num_poc_folders
                % Cannot move beyond list of folders
                handles.current_folder = handles.current_folder + 1;
                handles.data_folder = handles.all_poc_folders{handles.current_folder};
                handles = change_folder(app, handles);
            else
                % Cannot move forward a folder, so do nothing
                return
            end

            app.EditField.Value = '';
            
            guidata(hObject,handles);
        end

        % Button pushed function: next_quspe
        function next_quspe_Callback(app, event)
            % --- Executes on button press in next_quspe.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to next_quspe (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Move to the next good QUS/PE frame
            
            if handles.good_frame_idx ~= 0
                % When the frame index is zero, the buttons have not yet been pushed.
                % So can move to the first frame. Otherwise, be sure the frame index is
                % within bounds
                if handles.good_frame_idx >= length(handles.all_good_frames)
                    % At end of list, nothing to do
                    return;
                end
            end
            
            % Move to the previous frame in the list
            handles.good_frame_idx = handles.good_frame_idx + 1;
            frame_fid = handles.all_good_frames{handles.good_frame_idx};
            
            % Find the filename of the target frame in the list of all frames.
            all_frames = {handles.eye_fid.name};
            frame_idx = find(strcmp(frame_fid,all_frames));
            
            % Using the target frame index and the current index, determine the
            % increment for changing the scan plane
            frame_inc = frame_idx - handles.current_eye;
            
            handles = change_scan_plane(app, handles,frame_inc);
            
            guidata(hObject,handles);
        end

        % Value changed function: pe_checkbox
        function pe_checkbox_Callback(app, event)
            % --- Executes on button press in pe_checkbox.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pe_checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of pe_checkbox
            
            % If the box was checked, add the current file to the list of good PE
            % datasets. If it was unchecked, remove the current file from the list
            good_pe_cell = handles.good_pe_cell;
            if get(hObject,'Value') == 1
                add_remove = 'add';
            else
                add_remove = 'remove';
            end
            this_eye_num = handles.current_eye;
            this_eye = handles.eye_fid(this_eye_num).name;
            
            good_pe_cell = ssc.update_cell_list(good_pe_cell,this_eye,add_remove);
            handles.good_pe_cell = good_pe_cell;
            
            save_str = sprintf('%s\\%s',handles.data_folder,handles.poc_save_str);
            
            if exist(save_str,'file')
                save(save_str,'good_pe_cell','-append');
            else
                save(save_str,'good_pe_cell')
            end
            
            handles.all_good_frames = [handles.good_qus_cell, handles.good_pe_cell];
            
            % Save the PE results (if they exist)
            save_qus_pe(handles);
            
            % Update the number of good QUS/PE frames
            handles = update_qus_pe_count(handles);
            
            % Plot the ROI on the current frame, if necessary
            if handles.pe_checkbox.Value == 1
                handles = change_scan_plane(app, handles,0);
            end
            
            guidata(hObject,handles)
        end

        % Button pushed function: prev_button
        function prev_button_Callback(app, event)
            % --- Executes on button press in prev_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to prev_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %
            % Plot the B-mode of the previous .eye file
            handles = change_scan_plane(app, handles,-1);
            guidata(hObject,handles);
        end

        % Button pushed function: prev_folder
        function prev_folder_Callback(app, event)
            % --- Executes on button press in prev_folder.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to prev_folder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Move to the previous folder in the list of all POC folders
            if handles.current_folder > 1
                % Cannot move to folder 0
                handles.current_folder = handles.current_folder - 1;
                handles.data_folder = handles.all_poc_folders{handles.current_folder};
                handles = change_folder(app, handles);
            else
                % Cannot move back folder, so do nothing
                return
            end

            app.EditField.Value = '';
            
            guidata(hObject,handles);
        end

        % Button pushed function: prev_quspe
        function prev_quspe_Callback(app, event)
            % --- Executes on button press in prev_quspe.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to prev_quspe (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Move to the previous good QUS/PE frame
            
            if handles.good_frame_idx <= 1
                % At beginning of list. Nothing to do
                return;
            end
            
            % Move to the previous frame in the list
            handles.good_frame_idx = handles.good_frame_idx - 1;
            frame_fid = handles.all_good_frames{handles.good_frame_idx};
            
            % Find the filename of the target frame in the list of all frames.
            all_frames = {handles.eye_fid.name};
            frame_idx = find(strcmp(frame_fid,all_frames));
            
            % Using the target frame index and the current index, determine the
            % increment for changing the scan plane
            frame_inc = frame_idx - handles.current_eye;
            
            handles = change_scan_plane(app, handles,frame_inc);
            
            guidata(hObject,handles);
        end

        % Value changed function: qus_checkbox
        function qus_checkbox_Callback(app, event)
            % --- Executes on button press in qus_checkbox.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to qus_checkbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of qus_checkbox
            % If the box was checked, add the current file to the list of good PE
            % datasets. If it was unchecked, remove the current file from the list
            good_qus_cell = handles.good_qus_cell;
            if get(hObject,'Value') == 1
                add_remove = 'add';
            else
                add_remove = 'remove';
            end
            this_eye_num = handles.current_eye;
            this_eye = handles.eye_fid(this_eye_num).name;
            
            good_qus_cell = ssc.update_cell_list(good_qus_cell,this_eye,add_remove);
            handles.good_qus_cell = good_qus_cell;
            
            save_str = sprintf('%s\\%s',handles.data_folder,handles.poc_save_str);
            
            if exist(save_str,'file')
                save(save_str,'good_qus_cell','-append');
            else
                save(save_str,'good_qus_cell')
            end
            
            handles.all_good_frames = [handles.good_qus_cell, handles.good_pe_cell];
            
            % Save the QUS results (if they exist)
            save_qus_pe(handles);
            
            % Update the number of good QUS/PE frames
            handles = update_qus_pe_count(handles);
            
            % Plot the ROI on the current frame, if necessary
            if handles.qus_checkbox.Value == 1
                handles = change_scan_plane(app, handles,0);
            end
            
            guidata(hObject,handles)
        end

        % Button pushed function: run_pe_button
        function run_pe_button_Callback(app, event)
            % --- Executes on button press in run_pe_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to run_pe_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            eye_num = handles.current_eye;
            this_eye_fid = fullfile(handles.eye_fid(eye_num).folder,handles.eye_fid(eye_num).name);
            % this_eye_fid = handles.eye_fid(handles.current_eye).name;
            
            % Extract the imaging frequency from the filename to run the correct QUS
            % processing script
            [~,p_freq,~,~,~,~] = ssc.parse_ssc_file_name(this_eye_fid);
            
            if strcmp(p_freq,'50MHz')
                handles = ssc.run_pe_50_gui(this_eye_fid,handles.prf,handles);
            elseif strcmp(p_freq,'80MHz')
                handles = ssc.run_pe_80_gui(this_eye_fid,handles.prf,handles);
            end
            
            save_qus_pe(handles);
            
            % Plot the ROI on the GUI axes
            if handles.pe_checkbox.Value == 1
                change_scan_plane(app, handles,0);
            end
            
            guidata(hObject,handles)
        end

        % Button pushed function: run_qus_button
        function run_qus_button_Callback(app, event)
            % --- Executes on button press in run_qus_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to run_qus_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            eye_num = handles.current_eye;
            this_eye_fid = fullfile(handles.eye_fid(eye_num).folder,handles.eye_fid(eye_num).name);
            % this_eye_fid = handles.eye_fid(handles.current_eye).name;
            
            % Extract the imaging frequency from the filename to run the correct QUS
            % processing script
            [~,p_freq,~,~,~,~] = ssc.parse_ssc_file_name(this_eye_fid);

            % p_freq = '50MHz';
            
            if strcmp(p_freq,'50MHz')
                handles = ssc.run_qus_50_gui(this_eye_fid,handles);
            elseif strcmp(p_freq,'80MHz')
                handles = ssc.run_qus_80_gui(this_eye_fid,handles);
            end
            
            % Save the results if the checkbox has been selected
            save_qus_pe(handles);
            
            % Plot the ROI on the GUI axes
            if handles.qus_checkbox.Value == 1
                change_scan_plane(app, handles,0);
            end
            
            guidata(hObject,handles)
        end

        % Button pushed function: save_button
        function save_button_Callback(app, event)
            % --- Executes on button press in save_button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to save_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %
            % Save .png version of the B-mode image and plot of RF data along specified
            % A-line (only save the latter if it exists)
            
            % Create a new, invisible figure to plot the B-mode. If the B-mode in the
            % GUI is saved, the entire GUI figure window is saved in the image.
            b_fig = figure(800); gcf;
            set(gcf,'Visible','off');
            this_bmode = handles.rf_data.data;
            this_bmode = 20*log10(abs(hilbert(this_bmode)));
            this_bmode = this_bmode - max(this_bmode(:));
            
            imagesc(handles.rf_data.lateral_vec,handles.rf_data.axial_vec,this_bmode,[handles.db_range]);
            axis image;
            xlabel('Lateral (mm)');
            ylabel('Axial (mm)');
            set(gca,'FontSize',12);
            colorbar;
            colormap gray;
            % Put the patient/sample ID as the title
            [~,pat_id] = fileparts(handles.eye_fid(handles.current_eye).folder);
            title_str = sprintf('%s, Frame %d',pat_id,handles.current_eye);
            title(title_str,'Interpreter','None','FontSize',13);
            
            % If an A-line was plotted, add a red line indicating the position
            if handles.plot_aline == true
                a_pos = handles.aline_position;
                hold on
                plot([a_pos, a_pos],[handles.rf_data.axial_vec(1), handles.rf_data.axial_vec(end)],'r','LineWidth',1.25);
            end
            
            % If the ROI box was plotted, add a rectangle indicating the boundaries
            if handles.plot_roi == true
                lat_range = range([handles.roi_tl(1) handles.roi_br(1)]);
                ax_range = range([handles.roi_tl(2) handles.roi_br(2)]);
            
                rectangle('Position',[handles.roi_tl(1), handles.roi_tl(2), lat_range, ax_range], ...
                'Tag','roi_rect','EdgeColor','m','LineWidth',1.5);
            
                % Go ahead and recreate the figure displaying the B-mode within the ROI
                % and its average power spectrum. Then save the figure.
                plot_roi_boundary(app, hObject,eventdata,handles);
                saveas(figure(300),'fig_roi','png');
            end
            
            % Go ahead and plot a horizontal line at 13.5 to indicate the distal edge
            % of the focal zone for the 30MHz transducer
            lateral_vec = handles.rf_data.lateral_vec;
            axial_vec = handles.rf_data.axial_vec;
            figure(b_fig);
            hold(gca,'on');
            plot([lateral_vec(1), lateral_vec(end)],[10.75 10.75],':r','LineWidth',1.5,'Tag','roi_line');
            plot([lateral_vec(1), lateral_vec(end)],[13.5 13.5],':r','LineWidth',1.5,'Tag','roi_line');
            
            % Depth of field for the 10MHz transducer (2-27-20 estimate, at least)
            plot([lateral_vec(1), lateral_vec(end)],[32.91 32.91],':r','LineWidth',1.5,'Tag','roi_line');
            plot([lateral_vec(1), lateral_vec(end)],[44.54 44.54],':r','LineWidth',1.5,'Tag','roi_line');
            
            ax_lims = [lateral_vec(1) lateral_vec(end) axial_vec(1) axial_vec(end)];
            axis(ax_lims);
            
            saveas(b_fig,'fig_bmode','png');
            
            close(b_fig);
            
            if handles.plot_aline == true
                % An A-line has been plotted. Save the figure
                saveas(figure(200),'fig_aline','png');
            end
            
            % Print the full file name to the command window
            fprintf('\n%s\n',[handles.eye_fid(handles.current_eye).folder,'\',handles.eye_fid(handles.current_eye).name]);
            settings = handles.file_reader.settings;
            
            if handles.use_eye == true
                % Go ahead and print some of the info from the .eye file as well
                fprintf('\n%+20s:% 7.5f\n','vscale',settings.vscale);
                fprintf('%+20s:% -7d\n','gain',settings.gain);
                fprintf('%+20s:% -7d\n','bitsize',settings.bitsize);
                fprintf('%+20s:% -7d\n','energy',settings.energy);
                fprintf('%+20s:% -7d\n','attenuation',settings.attenuation);
                fprintf('%+20s:% -7d\n','damping',settings.damping);
                fprintf('%+20s:%7.2f\n','hpfilter',settings.hpfilter);
                fprintf('%+20s:%7.2f\n','lpfilter',settings.lpfilter);
                fprintf('%+20s:% -7d\n','prGain',settings.prGain);
                fprintf('%+20s:% -7d\n','deltaX',settings.deltaX);
                fprintf('%+20s:% -7d\n','deltaY',settings.deltaY);
                fprintf('%+20s:% 7.5f\n','fs',settings.fs);
            end
        end

        % Button pushed function: select_calib
        function select_calib_Callback(app, event)
            % --- Executes on button press in select_calib.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to select_calib (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [calib_folder] = uigetdir(cd,'Select Calibration Folder');
            handles.calib_folder = calib_folder;
            handles.text_calib_folder.String = handles.calib_folder;
            
            guidata(hObject,handles)
        end

        % Value changed function: upper_db_text
        function upper_db_text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to upper_db_text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of upper_db_text as text
            %        str2double(get(hObject,'String')) returns contents of upper_db_text as a double
            %
            % Set the upper dB bound
            %
            % First, make sure a number was entered and, if it was a number, that it is
            % not small than the dB range lower bound
            this_num = str2num(handles.upper_db_text.String);
            if isempty(this_num) || this_num < handles.db_range(1)
                % Make no change
                handles.upper_db_text.String = num2str(handles.db_range(2));
                guidata(hObject,handles);
                return
            end
            
            % Valid number in range, change the dB scale of the axes
            handles.db_range(2) = this_num;
            caxis(handles.axes1,handles.db_range);
            
            guidata(hObject,handles);
        end

        % Value changed function: UseoldgreenlinelocationCheckBox
        function UseoldgreenlinelocationCheckBoxValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            value = app.UseoldgreenlinelocationCheckBox.Value;
            if value == 1
                handles.oldgreenline = true;
            else
                handles.oldgreenline = false;
            end
            guidata(hObject,handles);
        end

        % Button pushed function: show_RF_lines_button
        function show_RF_lines_buttonButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.RFstruct.RF_xbounds = [];
            handles.RFstruct.qus_from_rflines = false;

            if isempty(handles.RFstruct.eye_mat)
                eye_dir = dir(fullfile(handles.eye_fid(1).folder,'*.eye'));
                eye_mat = [];
                if length(eye_dir) <= 150
                    m = 1;
                elseif length(eye_dir) <= 250
                    m = 50;
                else
                    m = 170;
                end
                for ii = m:length(eye_dir)
                    app.EditField.Value = ['Loading eye file ',num2str(ii)];
                    pause(.01);
                    % disp(['getting eye file ',num2str(ii)]);
                    this_path = fullfile(eye_dir(ii).folder,eye_dir(ii).name);
                    temp = eye.EyeData;
                    temp.readEye(this_path);
                    eye_mat = cat(2,eye_mat, temp.data);
                    if ii == m
                        handles.RFstruct.fs = temp.settings.fs*1e6;
                        handles.RFstruct.c = 1485; % Same as what is hard coded in LabView %temp.settings.sos;
                        handles.RFstruct.num_pts = temp.settings.rfpts;
                        handles.RFstruct.del_mm = temp.settings.del / temp.settings.sos * handles.RFstruct.c; %Changing offset due to change in SoStemp.settings.del; % axial offset
                    end
                    clear temp
                end
                handles.RFstruct.eye_mat = eye_mat;
                app.EditField.Value = ['Loaded all the ',num2str(ii), ' eye files.'];
            end

            if ~isempty(handles.RFstruct.eye_mat)
                app.EditField.Value = 'All RF lines are displayed.';
            end

            handles = show_RF_lines(app, handles, 'whole');
            handles.RFstruct.eye_mat_partial = [];

            % Update handles structure
            guidata(hObject,handles);
        end

        % Button pushed function: select_RF_lines_button
        function select_RF_lines_buttonButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            for i=1:2
                pos = drawpoint(app.axes3); %pos = [x,y]
                pos = round(pos.Position(1));
                hold(handles.axes3,'on');
                xline(app.axes3, pos, 'LineWidth',1.5,'LineStyle',':','Color','b');
                handles.RFstruct.RF_xbounds = cat(1,handles.RFstruct.RF_xbounds,pos);
            end

            handles.RFstruct.axes3_im = 'new selection';

            % Update handles structure
            guidata(hObject,handles);
        end

        % Button pushed function: show_selected_RF_lines_button
        function show_selected_RF_lines_buttonButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if isempty(handles.RFstruct.temp_eye_mat_partial)
                temp = [];
                for i = 1:2:length(handles.RFstruct.RF_xbounds)
                    temp = cat(2,temp,handles.RFstruct.eye_mat_partial(:,handles.RFstruct.RF_xbounds(i):handles.RFstruct.RF_xbounds(i+1)));
                end
                handles.RFstruct.temp_eye_mat_partial = temp;
            end

            handles = show_selected_RF_lines(app, handles, handles.RFstruct.temp_eye_mat_partial);

            % Update handles structure
            guidata(hObject,handles);
        end

        % Button pushed function: remove_RF_lines_button
        function remove_RF_lines_buttonButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            rem_pos = [];
            for i=1:2
                pos = drawpoint(app.axes3); %pos = [x,y]
                rem_pos(i) = round(pos.Position(1));
            end

            if isempty(handles.RFstruct.temp_eye_mat_partial)
                handles.RFstruct.temp_eye_mat_partial = ...
                    cat(2,handles.RFstruct.eye_mat_partial(:,1:rem_pos(1)),...
                    handles.RFstruct.eye_mat_partial(:,rem_pos(2):end));
            else
                handles.RFstruct.temp_eye_mat_partial = ...
                    cat(2,handles.RFstruct.temp_eye_mat_partial(:,1:rem_pos(1)),...
                    handles.RFstruct.temp_eye_mat_partial(:,rem_pos(2):end));
            end

            handles = show_selected_RF_lines(app, handles, handles.RFstruct.temp_eye_mat_partial);

            handles.RFstruct.axes3_im = 'new selection';
            app.EditField.Value = 'Removed manually selected RF lines!';

            % Update handles structure
            guidata(hObject,handles);
        end

        % Button pushed function: process_QUS_button
        function process_QUS_buttonButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % Select eye mat file that is actually shown in axes 3
            if strcmp(handles.RFstruct.axes3_im, 'new selection')
                handles.RFstruct.eye_mat_partial = handles.RFstruct.temp_eye_mat_partial;
            end

            % Parse the file name to get acquisition info
            eye_fid = handles.eye_fid(1).name;
            [~,p_freq,~,~,~,~] = ssc.parse_ssc_file_name(eye_fid);
            
            if strcmp(p_freq,'50MHz')
                handles = ssc.run_qus_50_partial_gui(handles);
            elseif strcmp(p_freq,'80MHz')
                handles = ssc.run_qus_80_partial_gui(handles);
            end        
            
            guidata(hObject,handles)
        end

        % Button pushed function: save_QUS_and_figures_button
        function save_QUS_and_figures_buttonButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            handles.RFstruct.qus_from_rflines = true;
            
            save_qus_pe_partial(app,handles);

            app.EditField.Value = 'Saved!';
            % disp('Saved!')
            guidata(hObject,handles)
        end

        % Button pushed function: automatic_RF_selection
        function automatic_RF_selectionButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.RFstruct.eye_mat_partial = [];
            handles.RFstruct.temp_eye_mat_partial = [];
            handles.RFstruct.RF_xbounds = [];
            if isempty(handles.RFstruct.eye_mat_partial)
                app.EditField.Value = 'Selecting ...';
                pause(.1);
                % disp('Selecting ...')
                handles = ssc.automatic_RF_selection(handles);
                if isfield(handles.RFstruct,'selected_RF')
                    handles.RFstruct.eye_mat_partial = handles.RFstruct.selected_RF;
                end
            end
            if isfield(handles.RFstruct,'selected_RF') & ~isempty(handles.RFstruct.selected_RF)
                app.EditField.Value = 'Selection done!';
                % disp('Selection done!')
                this_rf = handles.RFstruct.selected_RF;
                handles = show_selected_RF_lines(app, handles, this_rf);
            end
            % Save what is hown in axes3
            handles.RFstruct.axes3_im = 'original selection';

            % Update handles structure
            guidata(hObject,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ssc_poc_viewer';
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.ButtonDownFcn = createCallbackFcn(app, @axes1_ButtonDownFcn, true);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            xlabel(app.axes2, 'X')
            ylabel(app.axes2, 'Y')
            zlabel(app.axes2, 'Z')
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.figure1);
            xlabel(app.axes3, 'X')
            ylabel(app.axes3, 'Y')
            zlabel(app.axes3, 'Z')
            app.next_button = uibutton(app.figure1, 'push');
            app.next_button.ButtonPushedFcn = createCallbackFcn(app, @next_button_Callback, true);
            app.next_button.Tag = 'next_button';
            app.next_button.Text = 'Next';
            app.prev_button = uibutton(app.figure1, 'push');
            app.prev_button.ButtonPushedFcn = createCallbackFcn(app, @prev_button_Callback, true);
            app.prev_button.Tag = 'prev_button';
            app.prev_button.Text = 'Previous';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Tag = 'edit1';
            app.edit1.Value = 'Edit Text';
            app.num_files_text = uilabel(app.figure1);
            app.num_files_text.Tag = 'num_files_text';
            app.num_files_text.Text = 'Static Text';
            app.folder_button = uibutton(app.figure1, 'push');
            app.folder_button.ButtonPushedFcn = createCallbackFcn(app, @folder_button_Callback, true);
            app.folder_button.Tag = 'folder_button';
            app.folder_button.Text = 'Select Folder';
            app.folder_text = uitextarea(app.figure1);
            app.folder_text.ValueChangedFcn = createCallbackFcn(app, @folder_text_Callback, true);
            app.folder_text.Tag = 'folder_text';
            app.folder_text.Value = {'Static Text'};
            app.lower_db_text = uieditfield(app.figure1, 'text');
            app.lower_db_text.ValueChangedFcn = createCallbackFcn(app, @lower_db_text_Callback, true);
            app.lower_db_text.Tag = 'lower_db_text';
            app.lower_db_text.Value = '-40';
            app.upper_db_text = uieditfield(app.figure1, 'text');
            app.upper_db_text.ValueChangedFcn = createCallbackFcn(app, @upper_db_text_Callback, true);
            app.upper_db_text.Tag = 'upper_db_text';
            app.upper_db_text.Value = '0';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'to';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Normalized dB Range';
            app.save_button = uibutton(app.figure1, 'push');
            app.save_button.ButtonPushedFcn = createCallbackFcn(app, @save_button_Callback, true);
            app.save_button.Tag = 'save_button';
            app.save_button.Text = 'Save Images';
            app.max_v_text = uilabel(app.figure1);
            app.max_v_text.Tag = 'max_v_text';
            app.max_v_text.Text = 'Static Text';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'SHIFT+click = top-left corner';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'CTRL+click = bottom-right corner';
            app.filter_checkbox = uicheckbox(app.figure1);
            app.filter_checkbox.ValueChangedFcn = createCallbackFcn(app, @filter_checkbox_Callback, true);
            app.filter_checkbox.Tag = 'filter_checkbox';
            app.filter_checkbox.Text = 'Apply Filter';
            app.text_filename = uilabel(app.figure1);
            app.text_filename.Tag = 'text_filename';
            app.text_filename.Text = 'Selected File Name';
            app.checkbox2 = uicheckbox(app.figure1);
            app.checkbox2.ValueChangedFcn = createCallbackFcn(app, @checkbox2_Callback, true);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Text = 'Rescale A-line';
            app.pe_checkbox = uicheckbox(app.figure1);
            app.pe_checkbox.ValueChangedFcn = createCallbackFcn(app, @pe_checkbox_Callback, true);
            app.pe_checkbox.Tag = 'pe_checkbox';
            app.pe_checkbox.Text = 'Good PE Data';
            app.qus_checkbox = uicheckbox(app.figure1);
            app.qus_checkbox.ValueChangedFcn = createCallbackFcn(app, @qus_checkbox_Callback, true);
            app.qus_checkbox.Tag = 'qus_checkbox';
            app.qus_checkbox.Text = 'Good QUS Data';
            app.run_qus_button = uibutton(app.figure1, 'push');
            app.run_qus_button.ButtonPushedFcn = createCallbackFcn(app, @run_qus_button_Callback, true);
            app.run_qus_button.Tag = 'run_qus_button';
            app.run_qus_button.Text = 'Run QUS';
            app.run_pe_button = uibutton(app.figure1, 'push');
            app.run_pe_button.ButtonPushedFcn = createCallbackFcn(app, @run_pe_button_Callback, true);
            app.run_pe_button.Tag = 'run_pe_button';
            app.run_pe_button.Text = 'Run PE';
            app.prev_folder = uibutton(app.figure1, 'push');
            app.prev_folder.ButtonPushedFcn = createCallbackFcn(app, @prev_folder_Callback, true);
            app.prev_folder.Tag = 'prev_folder';
            app.prev_folder.Text = 'Prev Folder';
            app.next_folder = uibutton(app.figure1, 'push');
            app.next_folder.ButtonPushedFcn = createCallbackFcn(app, @next_folder_Callback, true);
            app.next_folder.Tag = 'next_folder';
            app.next_folder.Text = 'Next Folder';
            app.good_qus_str = uilabel(app.figure1);
            app.good_qus_str.Tag = 'good_qus_str';
            app.good_qus_str.Text = 'Good QUS: 0';
            app.good_pe_str = uilabel(app.figure1);
            app.good_pe_str.Tag = 'good_pe_str';
            app.good_pe_str.Text = 'Good PE: 0';
            app.prev_quspe = uibutton(app.figure1, 'push');
            app.prev_quspe.ButtonPushedFcn = createCallbackFcn(app, @prev_quspe_Callback, true);
            app.prev_quspe.Tag = 'prev_quspe';
            app.prev_quspe.Text = 'Prev QUS/PE';
            app.next_quspe = uibutton(app.figure1, 'push');
            app.next_quspe.ButtonPushedFcn = createCallbackFcn(app, @next_quspe_Callback, true);
            app.next_quspe.Tag = 'next_quspe';
            app.next_quspe.Text = 'Next QUS/PE';
            app.select_calib = uibutton(app.figure1, 'push');
            app.select_calib.ButtonPushedFcn = createCallbackFcn(app, @select_calib_Callback, true);
            app.select_calib.Tag = 'select_calib';
            app.select_calib.Text = 'Select Calibration Folder';
            app.text_calib_folder = uilabel(app.figure1);
            app.text_calib_folder.Tag = 'text_calib_folder';
            app.text_calib_folder.Text = 'Selected Calibration Folder';
            app.GreenlinelocationListBoxLabel = uilabel(app.figure1);
            app.GreenlinelocationListBoxLabel.Text = 'Green line location';
            app.GreenlinelocationListBox = uilistbox(app.figure1);
            app.GreenlinelocationListBox.Items = {'11.8', '12.3', '1.95'};
            app.GreenlinelocationListBox.Value = '11.8';
            app.UseoldgreenlinelocationCheckBox = uicheckbox(app.figure1);
            app.UseoldgreenlinelocationCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseoldgreenlinelocationCheckBoxValueChanged, true);
            app.UseoldgreenlinelocationCheckBox.Text = 'Use old green line location';
            app.show_RF_lines_button = uibutton(app.figure1, 'push');
            app.show_RF_lines_button.ButtonPushedFcn = createCallbackFcn(app, @show_RF_lines_buttonButtonPushed, true);
            app.show_RF_lines_button.Tag = 'save_button';
            app.show_RF_lines_button.Text = 'Show all RF lines';
            app.select_RF_lines_button = uibutton(app.figure1, 'push');
            app.select_RF_lines_button.ButtonPushedFcn = createCallbackFcn(app, @select_RF_lines_buttonButtonPushed, true);
            app.select_RF_lines_button.Tag = 'save_button';
            app.select_RF_lines_button.Text = 'Select RF lines';
            app.show_selected_RF_lines_button = uibutton(app.figure1, 'push');
            app.show_selected_RF_lines_button.ButtonPushedFcn = createCallbackFcn(app, @show_selected_RF_lines_buttonButtonPushed, true);
            app.show_selected_RF_lines_button.Tag = 'save_button';
            app.show_selected_RF_lines_button.Text = 'Show selected RF lines';
            app.remove_RF_lines_button = uibutton(app.figure1, 'push');
            app.remove_RF_lines_button.ButtonPushedFcn = createCallbackFcn(app, @remove_RF_lines_buttonButtonPushed, true);
            app.remove_RF_lines_button.Tag = 'save_button';
            app.remove_RF_lines_button.Text = 'Remove RF lines';
            app.process_QUS_button = uibutton(app.figure1, 'push');
            app.process_QUS_button.ButtonPushedFcn = createCallbackFcn(app, @process_QUS_buttonButtonPushed, true);
            app.process_QUS_button.Tag = 'save_button';
            app.process_QUS_button.Text = 'Process QUS';
            app.save_QUS_and_figures_button = uibutton(app.figure1, 'push');
            app.save_QUS_and_figures_button.ButtonPushedFcn = createCallbackFcn(app, @save_QUS_and_figures_buttonButtonPushed, true);
            app.save_QUS_and_figures_button.Tag = 'save_button';
            app.save_QUS_and_figures_button.Text = 'Save QUS and figures';
            app.automatic_RF_selection = uibutton(app.figure1, 'push');
            app.automatic_RF_selection.ButtonPushedFcn = createCallbackFcn(app, @automatic_RF_selectionButtonPushed, true);
            app.automatic_RF_selection.Tag = 'save_button';
            app.automatic_RF_selection.Text = 'RF lines with correct skin location, least corr';
            app.EditField = uieditfield(app.figure1, 'text');
            app.EditField.Value = 'Messages ...';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ssc_poc_viewer_App_automatic(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)ssc_poc_viewer_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end